classdef SignalSourceController < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        LogMenu                 matlab.ui.container.Menu
        ClearlogMenu            matlab.ui.container.Menu
        MoreMenu                matlab.ui.container.Menu
        AboutMenu               matlab.ui.container.Menu
        GridLayout              matlab.ui.container.GridLayout
        MinEditField            matlab.ui.control.NumericEditField
        MinEditFieldLabel       matlab.ui.control.Label
        SavedetectButton        matlab.ui.control.Button
        ChoosecamDropDown       matlab.ui.control.DropDown
        ChoosecamDropDownLabel  matlab.ui.control.Label
        BurstSwitch             matlab.ui.control.Switch
        BurstSwitchLabel        matlab.ui.control.Label
        LogTextArea             matlab.ui.control.TextArea
        LogTextAreaLabel        matlab.ui.control.Label
        N_cycleEditField        matlab.ui.control.NumericEditField
        N_cycleEditFieldLabel   matlab.ui.control.Label
        PeriodEditField         matlab.ui.control.NumericEditField
        PeriodEditFieldLabel    matlab.ui.control.Label
        RatioEditField          matlab.ui.control.NumericEditField
        RatioEditFieldLabel     matlab.ui.control.Label
        V_minEditField          matlab.ui.control.NumericEditField
        V_minEditFieldLabel     matlab.ui.control.Label
        V_maxEditField          matlab.ui.control.NumericEditField
        V_maxEditFieldLabel     matlab.ui.control.Label
        DetectcellsButton       matlab.ui.control.Button
        Image                   matlab.ui.control.Image
        StartcamButton          matlab.ui.control.Button
        AcquirecamlistButton    matlab.ui.control.Button
        CaptureButton           matlab.ui.control.Button
        SavelogButton           matlab.ui.control.Button
        Output1offButton        matlab.ui.control.Button
        Output1onButton         matlab.ui.control.Button
        sLabel_2                matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        VLabel_2                matlab.ui.control.Label
        VLabel                  matlab.ui.control.Label
        PulsesNCycleBurstsSettingsLabel  matlab.ui.control.Label
        SetButton               matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = public)
        log % String
        isBurst = 1;
        camList = webcamlist;
        cam;
        ImageHandle;
        isStreaming = false;
        Timer;
        SnapshotHandle;
        OverlayHandle;
        isCellDetection = false;
        frameForDetect;
        minCellSize=2000;
        % maxCellSize=5000;
    end
    
    methods (Access = public)
        function updateFrame(app)
            if app.isStreaming
                frame = snapshot(app.cam);
                app.ImageHandle.CData = frame;
            end
        end
        function dispLogInfo(app, str)
            string = sprintf("-> %s\n", str);
            app.log = strcat(app.log, string);
            app.LogTextArea.Value = app.log;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            V_max = app.V_maxEditField.Value;
            V_min = app.V_minEditField.Value;
            Ratio = app.RatioEditField.Value;
            Period = app.PeriodEditField.Value;
            N_cycle = app.N_cycleEditField.Value;
            formattedString = sprintf("Parameters set with:\nV_max=%.2fV\nV_min=%.2fV\nRatio=%.2f%%\nPeriod=%.3fs\nN_cycle=%d", V_max, V_min, Ratio, Period, N_cycle);
            dispLogInfo(app, formattedString);
            
            DG2052 = visa('NI', 'USB0::0x1AB1::0x0644::DG2P232400656::INSTR');
            fopen(DG2052);
            fprintf(DG2052, ':SOUR1:FUNC PULSE');
            fprintf(DG2052, ':SOUR1:VOLT:HIGH %f', V_max);
            fprintf(DG2052, ':SOUR1:VOLT:LOW %f', V_min);
            fprintf(DG2052, ':SOUR1:VOLT:OFFS 0');
            fprintf(DG2052, ':SOUR1:FUNC:PULS:DCYC 0.5');
            fprintf(DG2052, ':SOUR1:PER %f', Period);
            fprintf(DG2052, ':SOUR1:PHAS 0');
            fprintf(DG2052, ':SOUR1:BURS:STAT ON');
            fprintf(DG2052, ':SOUR1:BURS:IDLE 0');
            fprintf(DG2052, ':SOUR1:BURS:INT:PER 100');
            fprintf(DG2052, ':SOUR1:BURS:NCYC %f', N_cycle);
            fprintf(DG2052, ':SOUR1:BURS:MODE TRIG');
            fprintf(DG2052, ':SOUR1:BURS:TRIG:SOUR INT');
            fprintf(DG2052, ':SOUR1:BURS:TRIG:TRIGO OFF');
            fprintf(DG2052, ':SOUR1:BURS:TDEL 0.01');
            fclose(DG2052);
            clear DG2052;
        end

        % Button pushed function: Output1onButton
        function Output1onButtonPushed(app, event)
            dispLogInfo(app, "Output 1 ON.")
            app.Output1onButton.Enable = 'off';
            app.Output1offButton.Enable = 'on';
            
            DG2052 = visa('NI', 'USB0::0x1AB1::0x0644::DG2P232400656::INSTR');
            fopen(DG2052);
            fprintf(DG2052, ':OUTP1 ON');
            fclose(DG2052);
            clear DG2052;
        end

        % Button pushed function: Output1offButton
        function Output1offButtonPushed(app, event)
            dispLogInfo(app, "Output 1 OFF.")
            app.Output1onButton.Enable = 'on';

            DG2052 = visa('NI', 'USB0::0x1AB1::0x0644::DG2P232400656::INSTR');
            fopen(DG2052);
            fprintf(DG2052, ':OUTP1 OFF');
            fclose(DG2052);
            clear DG2052;
        end

        % Menu selected function: ClearlogMenu
        function ClearlogMenuSelected(app, event)
            app.log = "";
            app.LogTextArea.Value = "";
        end

        % Button pushed function: SavelogButton
        function SavelogButtonPushed(app, event)
            current_time = string(datetime('now', 'Format', 'yyyy-MM-dd''_''HH_mm_ss'));
            filename = sprintf('log-%s.txt',current_time);
            fileID = fopen(filename, 'w');
            fprintf(fileID, '%s', app.log);
            fclose(fileID);
        end

        % Value changed function: BurstSwitch
        function BurstSwitchValueChanged(app, event)
            value = app.BurstSwitch.Value;
            DG2052 = visa('NI', 'USB0::0x1AB1::0x0644::DG2P232400656::INSTR');
            fopen(DG2052);
            if value == "Off"
                app.isBurst = 0;
                dispLogInfo(app, "Burst OFF");
                fprintf(DG2052, ':SOUR1:BURS:STAT OFF');
            else
                app.isBurst = 1;
                dispLogInfo(app, "Burst ON");
                fprintf(DG2052, ':SOUR1:BURS:STAT ON');
            end
            fclose(DG2052);
            clear DG2052;
        end

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)
            app.frameForDetect = snapshot(app.cam);
            % [height, width, ~] = size(frame);
            app.Image.ImageSource = app.frameForDetect;
            app.Image.ScaleMethod = 'fit';
            dispLogInfo(app, "Snapshot captured.");
        end

        % Button pushed function: AcquirecamlistButton
        function AcquirecamlistButtonPushed2(app, event)
            app.camList = webcamlist;
            num_cam = length(app.camList);
            app.ChoosecamDropDown.Items = app.camList;
            camString = sprintf("Camera list:\n");
            for i=1:num_cam
                camString = camString + app.camList(i) + sprintf(";\n");
            end
            dispLogInfo(app, camString);
        end

        % Button pushed function: StartcamButton
        function StartcamButtonPushed(app, event)
            app.isStreaming = ~app.isStreaming;
            if app.isStreaming
                app.StartcamButton.Text = "Stop cam";
                app.StartcamButton.FontColor = "#f54236";
                dispLogInfo(app, "Cam started.");
            else
                app.StartcamButton.Text = "Start cam";
                app.StartcamButton.FontColor = "#000000";
                dispLogInfo(app, "Cam stopped.");
            end

            if app.isStreaming
                res = app.cam.Resolution;
                width = str2double(res(1:strfind(res,'x')-1));
                height = str2double(res(strfind(res,'x')+1:end));
                if isempty(app.UIAxes.Children)
                    app.ImageHandle = image(app.UIAxes, zeros(width,height,3));
                end
                app.UIAxes.XLim = [0 width];
                app.UIAxes.YLim = [0 height];
                app.UIAxes.DataAspectRatio = [1 1 1];
                app.UIAxes.Visible = 'off';
                if isempty(app.Timer) || ~isvalid(app.Timer)
                    app.Timer = timer('ExecutionMode', 'fixedRate', ...
                    'Period', 1/60, ...  % 60 Hz
                    'TimerFcn', @(~,~) updateFrame(app));
                end
                start(app.Timer);
            else
                if ~isempty(app.Timer) && isvalid(app.Timer)
                    stop(app.Timer);
                end
            end
        end

        % Value changed function: ChoosecamDropDown
        function ChoosecamDropDownValueChanged(app, event)
            value = app.ChoosecamDropDown.Value;
            app.cam = webcam(value);
            camChooseLogString = "Cam chosen:" + value;
            dispLogInfo(app, camChooseLogString);
        end

        % Button pushed function: DetectcellsButton
        function DetectcellsButtonPushed(app, event)
            % Convert to grayscale
            % I = imread("E:\Documents\Codes\Matlab\CellToolbox\cellphoto.jpg");
            GrayImage = rgb2gray(app.frameForDetect);
            % GrayImage = rgb2gray(I);

            % Edge detection
            [~,threshold] = edge(GrayImage,'sobel');
            fudgeFactor = 0.5;
            BWs = edge(GrayImage,'sobel',threshold * fudgeFactor);

            % Dilate
            se90 = strel('line',3,90);
            se0 = strel('line',3,0);
            BWsdil = imdilate(BWs,[se90 se0]);

            % Fill holes
            BWdfill = imfill(BWsdil,'holes');

            % Clear border
            BWnobord = imclearborder(BWdfill,4);

            % Erode
            seD = strel('diamond',1);
            BWfinal = imerode(BWnobord,seD);
            for i=2:10
                BWfinal = imerode(BWfinal,seD);
            end

            % Filter small objects
            BWfinal = bwareaopen(BWfinal, app.minCellSize);
            % Filter large objects
            % BWfinal = bwareafilt(BWfinal, [app.minCellSize, app.maxCellSize]);

            % Get properties of each cell region
            stats = regionprops(BWfinal, 'Centroid', 'Area', 'PixelList');

            % Create extended circles image
            extendedImage = false(size(BWfinal));

            % Process each cell
            for i = 1:length(stats)
                pixels = stats(i).PixelList;
                centroid = stats(i).Centroid;
                distances = sqrt(sum((pixels - centroid).^2, 2));
                radius = ceil(max(distances));

                [xx, yy] = meshgrid(1:size(BWfinal,2), 1:size(BWfinal,1));
                circlemask = (xx - centroid(1)).^2 + (yy - centroid(2)).^2 <= radius^2;
                extendedImage = extendedImage | circlemask;
            end

            % Create overlay image
            overlayImage = double(app.frameForDetect);  % Start with original image
            % overlayImage = double(I);
            alpha = 0.5;
            for c=1:3
                if c == 1 % Red channel
                    overlayImage(:,:,c) = overlayImage(:,:,c) .* ~extendedImage + ...
                    (overlayImage(:,:,c) .* (1-alpha) + 255 * alpha) .* extendedImage;
                else  % Green and Blue channels
                    overlayImage(:,:,c) = overlayImage(:,:,c) .* ~extendedImage + ...
                    (overlayImage(:,:,c) .* (1-alpha)) .* extendedImage;
                end
            end
            overlayImage = uint8(overlayImage);

            % Display in app.Image
            app.Image.ImageSource = overlayImage;
            dispLogInfo(app, "Cells detected.")
        end

        % Button pushed function: SavedetectButton
        function SavedetectButtonPushed(app, event)
            if ~exist('Snapshots', 'dir')
                mkdir('Snapshots');
            end
            current_time = datetime('now', 'Format', 'yyyy-MM-dd''_''HH_mm_ss');
            str0 = 'Snapshots\';
            str1 = sprintf('%s-Filter%d-OriginalImage',current_time,app.minCellSize);
            str2 = '.png';
            save_path = [str0,str1,str2];
            imwrite(app.frameForDetect, save_path);

            str1 = sprintf('%s-Filter%d-Overlay',current_time,app.minCellSize);
            save_path = [str0,str1,str2];
            imwrite(app.Image.ImageSource, save_path);
        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(app, event)
            value = app.MinEditField.Value;
            app.minCellSize = value;
        end

        % Callback function: not associated with a component
        function MaxEditFieldValueChanged(app, event)
            value = app.MaxEditField.Value;
            app.maxCellSize = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LogMenu = uimenu(app.UIFigure);
            app.LogMenu.Text = 'Log';
            app.ClearlogMenu = uimenu(app.LogMenu);
            app.ClearlogMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearlogMenuSelected, true);
            app.ClearlogMenu.Text = 'Clear log';
            app.MoreMenu = uimenu(app.UIFigure);
            app.MoreMenu.Text = 'More';
            app.AboutMenu = uimenu(app.MoreMenu);
            app.AboutMenu.Text = 'About';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SetButton = uibutton(app.GridLayout, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.PulsesNCycleBurstsSettingsLabel = uilabel(app.GridLayout);
            app.PulsesNCycleBurstsSettingsLabel.Text = 'Pulses & N-Cycle Bursts Settings';
            app.VLabel = uilabel(app.GridLayout);
            app.VLabel.Text = 'V';
            app.VLabel_2 = uilabel(app.GridLayout);
            app.VLabel_2.Text = 'V';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '%';
            app.sLabel_2 = uilabel(app.GridLayout);
            app.sLabel_2.Text = 's';
            app.Output1onButton = uibutton(app.GridLayout, 'push');
            app.Output1onButton.ButtonPushedFcn = createCallbackFcn(app, @Output1onButtonPushed, true);
            app.Output1onButton.Text = 'Output 1 on';
            app.Output1offButton = uibutton(app.GridLayout, 'push');
            app.Output1offButton.ButtonPushedFcn = createCallbackFcn(app, @Output1offButtonPushed, true);
            app.Output1offButton.Text = 'Output 1 off';
            app.SavelogButton = uibutton(app.GridLayout, 'push');
            app.SavelogButton.ButtonPushedFcn = createCallbackFcn(app, @SavelogButtonPushed, true);
            app.SavelogButton.Text = 'Save log';
            app.CaptureButton = uibutton(app.GridLayout, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Text = 'Capture';
            app.AcquirecamlistButton = uibutton(app.GridLayout, 'push');
            app.AcquirecamlistButton.ButtonPushedFcn = createCallbackFcn(app, @AcquirecamlistButtonPushed2, true);
            app.AcquirecamlistButton.Text = 'Acquire camlist';
            app.StartcamButton = uibutton(app.GridLayout, 'push');
            app.StartcamButton.ButtonPushedFcn = createCallbackFcn(app, @StartcamButtonPushed, true);
            app.StartcamButton.Text = 'Start cam';
            app.Image = uiimage(app.GridLayout);
            app.DetectcellsButton = uibutton(app.GridLayout, 'push');
            app.DetectcellsButton.ButtonPushedFcn = createCallbackFcn(app, @DetectcellsButtonPushed, true);
            app.DetectcellsButton.Text = 'Detect cells';
            app.V_maxEditFieldLabel = uilabel(app.GridLayout);
            app.V_maxEditFieldLabel.Text = 'V_max';
            app.V_maxEditField = uieditfield(app.GridLayout, 'numeric');
            app.V_maxEditField.Value = 1.5;
            app.V_minEditFieldLabel = uilabel(app.GridLayout);
            app.V_minEditFieldLabel.Text = 'V_min';
            app.V_minEditField = uieditfield(app.GridLayout, 'numeric');
            app.RatioEditFieldLabel = uilabel(app.GridLayout);
            app.RatioEditFieldLabel.Text = 'Ratio';
            app.RatioEditField = uieditfield(app.GridLayout, 'numeric');
            app.RatioEditField.Value = 0.5;
            app.PeriodEditFieldLabel = uilabel(app.GridLayout);
            app.PeriodEditFieldLabel.Text = 'Period';
            app.PeriodEditField = uieditfield(app.GridLayout, 'numeric');
            app.PeriodEditField.Value = 1;
            app.N_cycleEditFieldLabel = uilabel(app.GridLayout);
            app.N_cycleEditFieldLabel.Text = 'N_cycle';
            app.N_cycleEditField = uieditfield(app.GridLayout, 'numeric');
            app.N_cycleEditField.Value = 8;
            app.LogTextAreaLabel = uilabel(app.GridLayout);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.GridLayout);
            app.LogTextArea.Editable = 'off';
            app.LogTextArea.Placeholder = 'Return Info & Logs';
            app.BurstSwitchLabel = uilabel(app.GridLayout);
            app.BurstSwitchLabel.Text = 'Burst';
            app.BurstSwitch = uiswitch(app.GridLayout, 'slider');
            app.BurstSwitch.ValueChangedFcn = createCallbackFcn(app, @BurstSwitchValueChanged, true);
            app.ChoosecamDropDownLabel = uilabel(app.GridLayout);
            app.ChoosecamDropDownLabel.Text = 'Choose cam';
            app.ChoosecamDropDown = uidropdown(app.GridLayout);
            app.ChoosecamDropDown.Items = {};
            app.ChoosecamDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosecamDropDownValueChanged, true);
            app.ChoosecamDropDown.Placeholder = 'NULL.';
            app.ChoosecamDropDown.Value = {};
            app.SavedetectButton = uibutton(app.GridLayout, 'push');
            app.SavedetectButton.ButtonPushedFcn = createCallbackFcn(app, @SavedetectButtonPushed, true);
            app.SavedetectButton.Text = 'Save detect';
            app.MinEditFieldLabel = uilabel(app.GridLayout);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.GridLayout, 'numeric');
            app.MinEditField.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            app.MinEditField.Value = 2000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalSourceController

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end